classdef appLab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextArea            matlab.ui.control.TextArea
        TextArea_2          matlab.ui.control.TextArea
        OrientacinrpyLabel  matlab.ui.control.Label
        PosicinxyzLabel     matlab.ui.control.Label
        Joint6Slider        matlab.ui.control.Slider
        Joint6SliderLabel   matlab.ui.control.Label
        Joint5Slider        matlab.ui.control.Slider
        Joint5SliderLabel   matlab.ui.control.Label
        Joint4Slider        matlab.ui.control.Slider
        Joint4SliderLabel   matlab.ui.control.Label
        Joint3Slider        matlab.ui.control.Slider
        Joint3SliderLabel   matlab.ui.control.Label
        Joint2Slider        matlab.ui.control.Slider
        Joint2SliderLabel   matlab.ui.control.Label
        Joint1Slider        matlab.ui.control.Slider
        Joint1SliderLabel   matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        joint1 = 0 % Description
        joint2 = 0
        joint3 = 0
        joint4 = 0
        joint5 = 0
        joint6 = 0
        posicion= {''}
        orientacion= {''}
    end
    
    methods (Access = private)
        
        function results = cinematica_directa(app)
            syms q1 q2 q3 q4 q5 q6
            T01 = trotx(0)*transl(0,0,0.525)*trotz(q1);
            T12 = trotx(-pi/2)*transl(0.15,0,0)*trotz(q2-pi/2);
            T23 = trotx(0)*transl(0.77,0,0)*trotz(q3);
            T34 = trotx(-pi/2)*transl(0.1,0,0.740)*trotz(q4);
            T45 = trotx(pi/2)*transl(0,0,0)*trotz(q5);
            T56 = trotx(-pi/2)*transl(0,0,0.1)*trotz(q6-pi/2);
            T6NOA = trotz(0)*transl(0,0,0)*trotx(0);
            q1=app.joint1;
            q2=app.joint2;
            q3=app.joint3;
            q4=app.joint4;
            q5=app.joint5;           
            q6=app.joint6;
            % Modelo geométrico directo
            M0NOA=T01*T12*T23*T34*T45*T56*T6NOA;
            M0NOA_1=eval(M0NOA);
            app.posicion=M0NOA_1(1:3,4);
            app.orientacion=tr2rpy(M0NOA_1);
            app.TextArea.Value = {sprintf('%.6f',app.posicion(1)),sprintf('%.6f',app.posicion(2)),sprintf('%.6f',app.posicion(3))};
            app.TextArea_2.Value = {sprintf('%.6f',app.orientacion(1)),sprintf('%.6f',app.orientacion(2)),sprintf('%.6f',app.orientacion(3))};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            app.joint1 = app.Joint1Slider.Value;
            axis(app.UIAxes,'off')
            robot = loadrobot('fanucM16ib','DataFormat','row','Gravity',[0 0 -9.81]);
            show(robot,[app.joint1 app.joint2 -app.joint3 -app.joint4 -app.joint5 -app.joint6],'PreservePlot',false,'Frames',['on'])
            axis([-1,2,-1,2,-1,1.7])
            Frame= getframe(gcf);
            [image, Map]= frame2im(Frame);
            imshow(image,"Parent",app.UIAxes)
            cinematica_directa(app)
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            app.joint2 = app.Joint2Slider.Value;
            axis(app.UIAxes,'off')
            robot = loadrobot('fanucM16ib','DataFormat','row','Gravity',[0 0 -9.81]);
            show(robot,[app.joint1 app.joint2 -app.joint3 -app.joint4 -app.joint5 -app.joint6],'PreservePlot',false,'Frames',['on'])
            axis([-1,2,-1,2,-1,1.7])
            Frame= getframe(gcf);
            [image, Map]= frame2im(Frame);
            imshow(image,"Parent",app.UIAxes)
            cinematica_directa(app)
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            app.joint3 = app.Joint3Slider.Value;
            axis(app.UIAxes,'off')
            robot = loadrobot('fanucM16ib','DataFormat','row','Gravity',[0 0 -9.81]);
            show(robot,[app.joint1 app.joint2 -app.joint3 -app.joint4 -app.joint5 -app.joint6],'PreservePlot',false,'Frames',['on'])
            axis([-1,2,-1,2,-1,1.7])
            Frame= getframe(gcf);
            [image, Map]= frame2im(Frame);
            imshow(image,"Parent",app.UIAxes)
            cinematica_directa(app)
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            app.joint4 = app.Joint4Slider.Value;
            axis(app.UIAxes,'off')
            robot = loadrobot('fanucM16ib','DataFormat','row','Gravity',[0 0 -9.81]);
            show(robot,[app.joint1 app.joint2 -app.joint3 -app.joint4 -app.joint5 -app.joint6],'PreservePlot',false,'Frames',['on'])
            axis([-1,2,-1,2,-1,1.7])
            Frame= getframe(gcf);
            [image, Map]= frame2im(Frame);
            imshow(image,"Parent",app.UIAxes)
            cinematica_directa(app)
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            app.joint5 = app.Joint5Slider.Value;
            axis(app.UIAxes,'off')
            robot = loadrobot('fanucM16ib','DataFormat','row','Gravity',[0 0 -9.81]);
            show(robot,[app.joint1 app.joint2 -app.joint3 -app.joint4 -app.joint5 -app.joint6],'PreservePlot',false,'Frames',['on'])
            axis([-1,2,-1,2,-1,1.7])
            Frame= getframe(gcf);
            [image, Map]= frame2im(Frame);
            imshow(image,"Parent",app.UIAxes)
            cinematica_directa(app)
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            app.joint6 = app.Joint6Slider.Value;
            axis(app.UIAxes,'off')
            robot = loadrobot('fanucM16ib','DataFormat','row','Gravity',[0 0 -9.81]);
            show(robot,[app.joint1 app.joint2 -app.joint3 -app.joint4 -app.joint5 -app.joint6],'PreservePlot',false,'Frames',['on'])
            axis([-1,2,-1,2,-1,1.7])
            Frame= getframe(gcf);
            [image, Map]= frame2im(Frame);
            imshow(image,"Parent",app.UIAxes)
            cinematica_directa(app)
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            %value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-3 3];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-2.2 2.2];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-4 4];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-3.5 3.5];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-2.4 2.4];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-7.8 7.8];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.PosicinxyzLabel = uilabel(app.UIFigure);
            app.PosicinxyzLabel.Text = 'Posición (x,y,z)';
            app.OrientacinrpyLabel = uilabel(app.UIFigure);
            app.OrientacinrpyLabel.Text = 'Orientación (rpy)';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appLab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end